classdef CVtoolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        BasicoperationsTab             matlab.ui.container.Tab
        UploadphotoButton              matlab.ui.control.Button
        ConverttoGrayscaleButton       matlab.ui.control.Button
        addSPNoiseButton               matlab.ui.control.Button
        DensityofNoiseEditFieldLabel   matlab.ui.control.Label
        DensityofNoiseEditField        matlab.ui.control.NumericEditField
        AddLogoButton                  matlab.ui.control.Button
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        ConverttoHSVButton             matlab.ui.control.Button
        gethistogramButton             matlab.ui.control.Button
        histogramequlizationButton     matlab.ui.control.Button
        BlurButton                     matlab.ui.control.Button
        SobelOperatorButton            matlab.ui.control.Button
        LaplacianOperatorButton        matlab.ui.control.Button
        CannyOperatorButton            matlab.ui.control.Button
        HoughLinesButton               matlab.ui.control.Button
        HoughCirclesButton             matlab.ui.control.Button
        RadiusmaxEditFieldLabel        matlab.ui.control.Label
        RadiusmaxEditField             matlab.ui.control.NumericEditField
        RadiusminEditFieldLabel        matlab.ui.control.Label
        RadiusminEditField             matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxesO                        matlab.ui.control.UIAxes
        MorphologicalOperationTab      matlab.ui.container.Tab
        DilateButton                   matlab.ui.control.Button
        ErodeButton                    matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        UploadButton                   matlab.ui.control.Button
        xEditField_2Label              matlab.ui.control.Label
        xEditField_Sx                  matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_Sy                  matlab.ui.control.NumericEditField
        NonFlatBallCheckBox            matlab.ui.control.CheckBox
        DiskCheckBox                   matlab.ui.control.CheckBox
        ChooseStructuringElementLabel  matlab.ui.control.Label
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        LineCheckBox                   matlab.ui.control.CheckBox
        DimEditFieldLabel              matlab.ui.control.Label
        DimEditField                   matlab.ui.control.NumericEditField
        PosEditFieldLabel              matlab.ui.control.Label
        PosEditField                   matlab.ui.control.NumericEditField
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxesO1                       matlab.ui.control.UIAxes
        FiltersTab                     matlab.ui.container.Tab
        UploadphotoButton_2            matlab.ui.control.Button
        LogFilterButton                matlab.ui.control.Button
        KernalSizeEditFieldLabel       matlab.ui.control.Label
        KernalSizeEditField1           matlab.ui.control.NumericEditField
        FiltersLabel                   matlab.ui.control.Label
        PrewittButton                  matlab.ui.control.Button
        AverageButton                  matlab.ui.control.Button
        EditField1                     matlab.ui.control.NumericEditField
        EditField2                     matlab.ui.control.NumericEditField
        EditField3                     matlab.ui.control.NumericEditField
        EditField4                     matlab.ui.control.NumericEditField
        EditField5                     matlab.ui.control.NumericEditField
        EditField6                     matlab.ui.control.NumericEditField
        EditField7                     matlab.ui.control.NumericEditField
        EditField8                     matlab.ui.control.NumericEditField
        EditField9                     matlab.ui.control.NumericEditField
        CustomizedKernalLabel          matlab.ui.control.Label
        SigmaGaussianEditFieldLabel    matlab.ui.control.Label
        SigmaGaussianEditField         matlab.ui.control.NumericEditField
        ApplyFilterButton              matlab.ui.control.Button
        XLabel                         matlab.ui.control.Label
        KernalSizeEditField2           matlab.ui.control.NumericEditField
        DiskButton                     matlab.ui.control.Button
        DiskRadiusEditFieldLabel       matlab.ui.control.Label
        DiskRadiusEditField            matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxesO2                       matlab.ui.control.UIAxes
        ContoursTab                    matlab.ui.container.Tab
        UploadphotoButton_3            matlab.ui.control.Button
        FindConnectedButton            matlab.ui.control.Button
        BoundingBoxButton              matlab.ui.control.Button
        DrawRedContourButton           matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxesO3                       matlab.ui.control.UIAxes
        videoTab                       matlab.ui.container.Tab
        AddNoisePanel                  matlab.ui.container.Panel
        addSPNoiseButton_2             matlab.ui.control.Button
        GaussiannoiseButton            matlab.ui.control.Button
        PoissonNoiseButton             matlab.ui.control.Button
        NoiseDensityEditFieldLabel     matlab.ui.control.Label
        NoiseDensityEditField          matlab.ui.control.NumericEditField
        UIAxesvideo                    matlab.ui.control.UIAxes
        UIAxesvideoO                   matlab.ui.control.UIAxes
        UploadVideoButton              matlab.ui.control.Button
        FilteringPanel                 matlab.ui.container.Panel
        converttoHSVButton             matlab.ui.control.Button
        BlurVideoButton                matlab.ui.control.Button
        BlurValueEditFieldLabel        matlab.ui.control.Label
        BlurValueEditField             matlab.ui.control.NumericEditField
        Rgb2grayButton                 matlab.ui.control.Button
        EdgeDetectionPanel             matlab.ui.container.Panel
        SobelButton                    matlab.ui.control.Button
        LaplacianOperatorButton_2      matlab.ui.control.Button
        CannyOperatorButton_2          matlab.ui.control.Button
        PrewittButton_2                matlab.ui.control.Button
        RobertsButton                  matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        app.ConverttoGrayscaleButton.Enable='off';
       
        app.addSPNoiseButton.Enable='off';
        app.AddLogoButton.Enable='off';
        app.ConverttoHSVButton.Enable='off';
        app.gethistogramButton.Enable='off';
        app.histogramequlizationButton.Enable='off';
       
        app.BlurButton.Enable='off';
        app.LaplacianOperatorButton.Enable='off';
        app.CannyOperatorButton.Enable='off';
        app.SobelOperatorButton.Enable='off';
        app.HoughCirclesButton.Enable='off';
        app.HoughLinesButton.Enable='off';
        app.DilateButton.Enable='off';
        app.ErodeButton.Enable='off';
        app.OpenButton.Enable='off';
        app.CloseButton.Enable='off';
        app.LogFilterButton.Enable='off';
        app.PrewittButton.Enable='off';
        app.PrewittButton.Enable='off';
        app.ApplyFilterButton.Enable='off';
         
         app.DiskButton.Enable='off';
         app.AverageButton.Enable='off';
           
         app.FindConnectedButton.Enable='off';
         app.BoundingBoxButton.Enable='off';
         app.DrawRedContourButton.Enable='off';
        app.converttoHSVButton.Enable='off';
        app.BlurVideoButton.Enable='off';
        app.Rgb2grayButton.Enable='off';
        app.SobelButton.Enable='off';
        app.LaplacianOperatorButton_2.Enable='off';
        app.CannyOperatorButton_2.Enable='off';
        app.PrewittButton_2.Enable='off';
        app.RobertsButton.Enable='off';
                   
      app.addSPNoiseButton_2.Enable='off';
       app.GaussiannoiseButton.Enable='off';
       app.PoissonNoiseButton.Enable='off';
                   
        end

        % Button pushed function: UploadphotoButton
        function UploadphotoButtonPushed(app, event)


            path_to_folder = '../data/'; 

            [filename, pathname] = uigetfile('*.jpg');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
         
                return;
                
            end
            
            
           global im2
           
            [im2,~]=imread([pathname, filename]);
           
            image(im2,'Parent',app.UIAxes);     
          set(app.UIAxes, 'handlevisibility','off','visible','off')
          
          
           app.ConverttoGrayscaleButton.Enable='on';
       
      app.addSPNoiseButton.Enable='on';
       app.AddLogoButton.Enable='on';
       app.ConverttoHSVButton.Enable='on';
        app.gethistogramButton.Enable='on';
       app.histogramequlizationButton.Enable='on';
         
         app.BlurButton.Enable='on';
        app.LaplacianOperatorButton.Enable='on';
        app.CannyOperatorButton.Enable='on';
        app.SobelOperatorButton.Enable='on';
        app.HoughCirclesButton.Enable='on';
        app.HoughLinesButton.Enable='on';
         
        end

        % Button pushed function: ConverttoGrayscaleButton
        function ConverttoGrayscaleButtonPushed(app, event)
            global im2 
            global map
            gray1 = rgb2gray(im2);
              
            imagesc(gray1,'Parent',app.UIAxesO);
            colormap(app.UIAxesO, gray);
             set(app.UIAxesO, 'handlevisibility','off','visible','off')
           
            
        end

        % Button pushed function: addSPNoiseButton
        function addSPNoiseButtonPushed(app, event)
            global im2
            d=app.DensityofNoiseEditField.Value;
            Noisy = imnoise(im2,'salt & pepper',d)
            imagesc(Noisy,'Parent',app.UIAxesO);
             set(app.UIAxesO, 'handlevisibility','off','visible','off')
           
        end

        % Button pushed function: AddLogoButton
        function AddLogoButtonPushed(app, event)
             path_to_folder = '../data/'; 
%                   folder_name = uigetdir
            [filename, pathname] = uigetfile('*.jpg');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
                return;
  
            end
           global logo
            global im2
            
            logo=imread([pathname, filename]);          
            
                 image(im2);
              % GET handle to current axes and move the plot axes to the bottom
                  ha =gca;
                uistack(ha,'bottom');
                
                % Creating a new axes for the logo on the current axes
                  % To create the logo at the bottom left corner of the plot use 
%                 % the next two lines


%                     haPos = get(ha,'position');
%                     ha2=axes('position',[haPos(1:2), .15,.15,]);
                    
                    
                  % To place the logo at the bottom left corner of the figure window
                 % uncomment the line below and comment the above two lines
                    x=app.xEditField.Value;
                    y=app.yEditField.Value;
                     ha2=axes('position',[x, y, .1,.1]);
%                                  Adding a LOGO to the new axes
                       
                       [x, map]=imread([pathname, filename]);
                       image(x)
                       
                      % Setting the colormap to the colormap of the imported logo image
                      
                      colormap (map)
                      
                   
                         set(ha2,'handlevisibility','off','visible','off')  
        
        end

        % Button pushed function: ConverttoHSVButton
        function ConverttoHSVButtonPushed(app, event)
          
            
            global im2
            
            HSV=rgb2hsv(im2);
            set(app.UIAxesO, 'handlevisibility','off','visible','off')
            image(HSV,'Parent',app.UIAxesO);


        end

        % Button pushed function: gethistogramButton
        function gethistogramButtonPushed(app, event)
            
            global im2
            
           [histoC,x] = imhist(im2);
           
           stem(x,histoC,'Parent',app.UIAxesO);
           
           set(app.UIAxesO,'YDir','normal', 'handlevisibility','on','visible','on')


        end

        % Button pushed function: histogramequlizationButton
        function histogramequlizationButtonPushed(app, event)
            global im2
            
            equalization = histeq(im2);
            
            image(equalization,'Parent',app.UIAxesO);
            set(app.UIAxesO, 'handlevisibility','off','visible','off');


        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
              path_to_folder = '../data/'; 
%                   folder_name = uigetdir
            [filename, pathname] = uigetfile('*.jpg');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
               return;

            end
           
            global im3
            global map3
            [im3,map3]=imread([pathname, filename]);
           
            imagesc(im3,'Parent',app.UIAxes1);   
            set(app.UIAxes1, 'handlevisibility','off','visible','off');
            app.DilateButton.Enable='on';
            app.ErodeButton.Enable='on';
            app.OpenButton.Enable='on';
            app.CloseButton.Enable='on';


        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            global im3
           
            global se
            
            dilateimage = imdilate(im3,se);
            
             imagesc(dilateimage,'Parent',app.UIAxesO1);
            
            set(app.UIAxesO1, 'handlevisibility','off','visible','off');
        end

        % Value changed function: NonFlatBallCheckBox
        function NonFlatBallCheckBoxValueChanged(app, event)
            global valueC1
            global se
            valueC1 = app.NonFlatBallCheckBox.Value;
            sizeX = app.xEditField_Sx.Value;
            sizeY = app.yEditField_Sy.Value;
            
            if valueC1 == 1
               se = offsetstrel('ball',sizeX,sizeY);
            end
        end

        % Value changed function: DiskCheckBox
        function DiskCheckBoxValueChanged(app, event)
            global se
            valueD1 = app.DiskCheckBox.Value;
            DiskSize = app.RadiusEditField.Value;
            
             if valueD1 == 1
               se = strel('disk',DiskSize);
            end
            
        end

        % Value changed function: LineCheckBox
        function LineCheckBoxValueChanged(app, event)
            
            global se
            valueLine1H = app.LineCheckBox.Value;
            LineDimension = app.DimEditField.Value;
            LinePosition = app.PosEditField.Value;
            
             if valueLine1H == 1
               se = strel('line', LineDimension,LinePosition);
            end
            
            
        end

        % Callback function
        function VLineCheckBoxValueChanged(app, event)
           
            
            global se
            
            valueLine2V = app.VLineCheckBox.Value;
            LineDimension = app.DimEditField.Value;
            LinePosition = app.PosEditField.Value;
            
             if valueLine2V == 1
               se = strel('line', LineDimension,LinePosition);
            end


        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
             global im3
           
            global se
            
            erodeimage = imerode(im3,se);
            
             imagesc(erodeimage,'Parent',app.UIAxesO1);
            
            set(app.UIAxesO1, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global im3
           
            global se
            
            openimage = imopen(im3,se);
            
             imagesc(openimage,'Parent',app.UIAxesO1);
            set(app.UIAxesO1, 'handlevisibility','off','visible','off');
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            global im3
           
            global se
            
            closeimage = imclose(im3,se);
            
             image(closeimage,'Parent',app.UIAxesO1);
            set(app.UIAxesO1, 'handlevisibility','off','visible','off');
            
        end

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
           global im2
           Sigma = app.SigmaEditField.Value
           Iblur = imgaussfilt(im2, Sigma);
           
            image(Iblur,'Parent',app.UIAxesO);
           set(app.UIAxesO, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: SobelOperatorButton
        function SobelOperatorButtonPushed(app, event)
            global im2
           imgray = rgb2gray(im2)


             sobel = edge( imgray,'sobel');
           
             imagesc(sobel,'Parent',app.UIAxesO);
            colormap(app.UIAxesO, gray);
         set(app.UIAxesO, 'handlevisibility','off','visible','off');

        end

        % Button pushed function: LaplacianOperatorButton
        function LaplacianOperatorButtonPushed(app, event)
              global im2
              
                 imgray = rgb2gray(im2)


             Laplace = edge( imgray,'log');
           
             imagesc(Laplace,'Parent',app.UIAxesO);
            colormap(app.UIAxesO, gray);
         set(app.UIAxesO, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: CannyOperatorButton
        function CannyOperatorButtonPushed(app, event)
            global im2
                 
            imgray = rgb2gray(im2)


             canny = edge( imgray,'Canny');
           
             imagesc(canny,'Parent',app.UIAxesO);
            colormap(app.UIAxesO, gray);
         set(app.UIAxesO, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: HoughLinesButton
        function HoughLinesButtonPushed(app, event)

        global im2 
            
            
       I  = im2;
       
       igray = rgb2gray(I); %convert to grayscale in order to do canny filter

       BW = edge(igray,'canny');
       
        [H,T,R] = hough(BW);

       P  = houghpeaks(H,5,'threshold',ceil(0.6*max(H(:)))); % get the peaks of the hough transform

            
     lines = houghlines(BW,T,R,P,'FillGap',5,'MinLength',7);
       imshow(igray), hold on
       max_len = 0;
for k = 1:length(lines)
   xy = [lines(k).point1; lines(k).point2];
   plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green');

   % Plot beginnings and ends of lines
   plot(xy(1,1),xy(1,2),'x','LineWidth',1,'Color','yellow');
   plot(xy(2,1),xy(2,2),'x','LineWidth',1,'Color','red');

   % Determine the endpoints of the longest line segment
   len = norm(lines(k).point1 - lines(k).point2);
   if ( len > max_len)
      max_len = len;
      xy_long = xy;
   end
end

plot(xy_long(:,1),xy_long(:,2),'LineWidth',2,'Color','red'); % to plot the largest line
            
        end

        % Button pushed function: HoughCirclesButton
        function HoughCirclesButtonPushed(app, event)
          global im2
             
       
            imshow(im2)
            [centers, radii, ~] = imfindcircles(im2,[15 100]);

            viscircles(centers, radii,'EdgeColor','r');


        end

        % Button pushed function: UploadphotoButton_2
        function UploadphotoButton_2Pushed(app, event)
            path_to_folder = '../data/'; 
%                   folder_name = uigetdir
            [filename, pathname] = uigetfile('*.jpg','*.png');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
                return;
            end
            
           global im3
            global map3
            [im3,map3]=imread([pathname, filename]);
           
            imagesc(im3,'Parent',app.UIAxes2);     
            
         set(app.UIAxes2, 'handlevisibility','off','visible','off');
         app.LogFilterButton.Enable='on';
         app.PrewittButton.Enable='on';
         app.PrewittButton.Enable='on';
         app.ApplyFilterButton.Enable='on';
         
         app.DiskButton.Enable='on';
         app.AverageButton.Enable='on';


        end

        % Button pushed function: LogFilterButton
        function LogFilterButtonPushed(app, event)
             global im3
           Sigma = app.SigmaGaussianEditField.Value
           a1 =  app.KernalSizeEditField1.Value;
           a2 = app.KernalSizeEditField2.Value;
           hsize = [a1 a2];
           h = fspecial('log',hsize,Sigma);
           Igauss = imfilter(im3, h);
           
            image(Igauss,'Parent',app.UIAxesO2);
           
         set(app.UIAxesO2, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            global im3
            
            a11 = app.EditField1.Value;
            a12 = app.EditField2.Value;
            a13 = app.EditField3.Value;
            a21 = app.EditField4.Value;
            a22 = app.EditField5.Value;
            a23 = app.EditField6.Value;
            a31 = app.EditField7.Value;
            a32 = app.EditField8.Value;
            a33 = app.EditField9.Value;
         FilterElFalater = [a11 a12 a13; a21 a22 a23; a31 a32 a33];
         apply = imfilter(im3,FilterElFalater);
         image(apply,'Parent',app.UIAxesO2);
            
         set(app.UIAxesO2, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global im3
            
            grayyy = rgb2gray(im3)
            h = fspecial('prewitt');
             apply = imfilter(grayyy, h);
         image(apply,'Parent',app.UIAxesO2);
         colormap(app.UIAxesO2, gray);
         
         set(app.UIAxesO2, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
              global im3
          
           a1 =  app.KernalSizeEditField1.Value;
           a2 = app.KernalSizeEditField2.Value;
         
           hsize = [a1 a2];
         
           h = fspecial('average',hsize);

           Iaverage = imfilter(im3, h);
           
            imagesc(Iaverage,'Parent',app.UIAxesO2);
            
         set(app.UIAxesO2, 'handlevisibility','off','visible','off');
        end

        % Button pushed function: DiskButton
        function DiskButtonPushed(app, event)
           global im3
           radius = app.DiskRadiusEditField.Value
           h = fspecial('disk',radius);

           Idisk = imfilter(im3, h);
           
            imagesc( Idisk,'Parent',app.UIAxesO2);
        end

        % Button pushed function: UploadphotoButton_3
        function UploadphotoButton_3Pushed(app, event)
            path_to_folder = '../data/'; 
%                   folder_name = uigetdir
            [filename, pathname] = uigetfile('*.jpg','*.png');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
                return;
            end
            
           global im4
            global map4
            [im4,map4]=imread([pathname, filename]);
           
            image(im4,'Parent',app.UIAxes3);  
            
       
         set(app.UIAxes3 ,'handlevisibility','off','visible','off');
         
         app.FindConnectedButton.Enable='on';
         app.BoundingBoxButton.Enable='on';
         app.DrawRedContourButton.Enable='on';


        end

        % Button pushed function: FindConnectedButton
        function FindConnectedButtonPushed(app, event)
           global im4
           
           global F
           global B
           global L
           global c
           
           if size(im4,3) == 3
            c =  rgb2gray(im4)
            F = c<128;
            [B,L,N,A] = bwboundaries(F);
            v =  imcomplement(F);
             imshow(v); 
           elseif size(im4,3) == 1
               c = im4;
              [B,L,N,A] = bwboundaries(c);
              v =  imcomplement(c);
              imshow(v)
           else
                [B,L,N,A] = bwboundaries(im4);
                v =  imcomplement(im4);
                imshow(v); 
           end
           
            hold on ;
          
            colors=['b' 'g' 'r' 'c' 'm' 'y'];
            
            for k=1:length(B),
                boundary = B{k};
                cidx = mod(k,length(colors))+1;
                plot(boundary(:,2), boundary(:,1),...
                 colors(cidx),'LineWidth',2);

 
                 rndRow = ceil(length(boundary)/(mod(rand*k,7)+1));
                 col = boundary(rndRow,2); row = boundary(rndRow,1);
                 h = text(col+1, row-1, num2str(L(row,col)));
                 set( h,'Color',colors(cidx),'FontSize',8);
             end
        end

        % Button pushed function: BoundingBoxButton
        function BoundingBoxButtonPushed(app, event)
            global im4
            global labeledI
           global h
           if size(im4,3) == 3
                c =  rgb2gray(im4)
                F = c<128;
                h=F;
                 
                labeledI = bwlabel(F);
                

           else
                labeledI = bwlabel(im4);
               h=im4;
                
           end  
%            h = imcomplement(h);
           imshow(h);
           hold on;


            measurements = regionprops(labeledI, 'BoundingBox', 'Area');
           
            for k = 1 : length(measurements)
                 thisBB = measurements(k).BoundingBox;
                    rectangle('Position', [thisBB(1),thisBB(2),thisBB(3),thisBB(4)],...
                        'EdgeColor','r','LineWidth',2 )
            end
            
            
        end

        % Button pushed function: DrawRedContourButton
        function DrawRedContourButtonPushed(app, event)
            global im4
            B = rgb2gray(im4);
            A = B < 127.5;
        [B,~] = bwboundaries(A);
        
        % show images
       
        imshow(A, [])
        hold on;
        for k = 1:length(B)
            boundary = B{k};
            plot(boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2)
        end
        hold off;
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
             
             app.converttoHSVButton.Enable='on';
            app.BlurVideoButton.Enable='on';
           app.Rgb2grayButton.Enable='on';
       app.SobelButton.Enable='on';
       app.LaplacianOperatorButton_2.Enable='on';
       app.CannyOperatorButton_2.Enable='on';
       app.PrewittButton_2.Enable='on';
       app.RobertsButton.Enable='on'; 
       app.addSPNoiseButton_2.Enable='on';
       app.GaussiannoiseButton.Enable='on';
       app.PoissonNoiseButton.Enable='on';
       
            path_to_folder = '../data/'; 

            [filename, pathname] = uigetfile('*.mpg');
                 app.UIFigure.Visible = 'off';
                 app.UIFigure.Visible = 'on';
                 
            
            if ~ischar(filename)
                disp('User canceled uploading');
         
                return;
                
            end
            
            
           global v1
           global video
          v1 = VideoReader([pathname, filename]);
          video = read(v1);
         
          nFrames = size(video,4);
         global exitloop
          exitloop = 0;
                for i = 1 : nFrames     
                    
%                       I(:,:,:,i)  = imnoise(video(:,:,:,i),'gaussian');
                        I(:,:,:,i)  = video(:,:,:,i);
                     image(I(:,:,:,i),'Parent',app.UIAxesvideo);  
                     drawnow;
                     if exitloop==1
                        
                         return;
                       
                     end
                     set(app.UIAxesvideo, 'handlevisibility','off','visible','off')
                     
                end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           global exitloop
           
           exitloop=1;
     
           delete(app)
          
        end

        % Callback function
        function CloseVideowindowButtonPushed(app, event)

        end

        % Button pushed function: addSPNoiseButton_2
        function addSPNoiseButton_2Pushed(app, event)
               
            global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = imnoise(video(:,:,:,i),'salt & pepper',app.NoiseDensityEditField.Value);
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: GaussiannoiseButton
        function GaussiannoiseButtonPushed(app, event)
               global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = imnoise(video(:,:,:,i),'gaussian',app.NoiseDensityEditField.Value);
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: PoissonNoiseButton
        function PoissonNoiseButtonPushed(app, event)
            global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = imnoise(video(:,:,:,i),'speckle',app.NoiseDensityEditField.Value);
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
      
   
        end

        % Button pushed function: Rgb2grayButton
        function Rgb2grayButtonPushed(app, event)
            global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = rgb2gray(video(:,:,:,i));
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: converttoHSVButton
        function converttoHSVButtonPushed(app, event)
                global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = rgb2hsv(video(:,:,:,i));
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  

                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: BlurVideoButton
        function BlurVideoButtonPushed(app, event)
            
                global video
            global exitloop
             nFrames = size(video,4);
        
            
                for i = 1 : nFrames     
                    
                      I(:,:,:,i)  = imgaussfilt(video(:,:,:,i),app.BlurValueEditField.Value);
                       
                     image(I(:,:,:,i),'Parent',app.UIAxesvideoO);  

                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
           
              
            global video
            global exitloop
            nFrames = size(video,4);
        
           
                for i = 1 : nFrames     
%                       B(:,:,:,i)  = rgb2gray(video(:,:,:,i));
%                        v  = 
                      I(:,:,:,i)  = edge(rgb2gray(video(:,:,:,i)),'sobel');
                       
                     imagesc(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: LaplacianOperatorButton_2
        function LaplacianOperatorButton_2Pushed(app, event)
              global video
            global exitloop
            nFrames = size(video,4);
        
           
                for i = 1 : nFrames     
%                      
                      I(:,:,:,i)  = edge(rgb2gray(video(:,:,:,i)),'log');
                       
                     imagesc(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: CannyOperatorButton_2
        function CannyOperatorButton_2Pushed(app, event)
               global video
            global exitloop
            nFrames = size(video,4);
        
           
                for i = 1 : nFrames     
%                      
                      I(:,:,:,i)  = edge(rgb2gray(video(:,:,:,i)),'canny');
                       
                     imagesc(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: PrewittButton_2
        function PrewittButton_2Pushed(app, event)
                    global video
            global exitloop
            nFrames = size(video,4);
        
           
                for i = 1 : nFrames     
%                      
                      I(:,:,:,i)  = edge(rgb2gray(video(:,:,:,i)),'Prewitt');
                       
                     imagesc(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
                     global video
            global exitloop
            nFrames = size(video,4);
        
           
                for i = 1 : nFrames     
%                      
                      I(:,:,:,i)  = edge(rgb2gray(video(:,:,:,i)),'Roberts');
                       
                     imagesc(I(:,:,:,i),'Parent',app.UIAxesvideoO);  
                      colormap(app.UIAxesvideoO, gray);
                     drawnow;
                     
                     if exitloop==1
                        
                         return;
                       
                     end
                     
                     set(app.UIAxesvideoO, 'handlevisibility','off','visible','off')
                     
                end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicoperationsTab = uitab(app.TabGroup);
            app.BasicoperationsTab.Title = 'Basic operations';
            app.UploadphotoButton = uibutton(app.BasicoperationsTab, 'push');
            app.UploadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButtonPushed, true);
            app.UploadphotoButton.Text = 'Upload photo';
            app.ConverttoGrayscaleButton = uibutton(app.BasicoperationsTab, 'push');
            app.ConverttoGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoGrayscaleButtonPushed, true);
            app.ConverttoGrayscaleButton.Text = 'Convert to Grayscale';
            app.addSPNoiseButton = uibutton(app.BasicoperationsTab, 'push');
            app.addSPNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @addSPNoiseButtonPushed, true);
            app.addSPNoiseButton.Text = 'add S&P Noise';
            app.DensityofNoiseEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.DensityofNoiseEditFieldLabel.Text = 'Density of Noise';
            app.DensityofNoiseEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.DensityofNoiseEditField.Limits = [0 1];
            app.DensityofNoiseEditField.Value = 0.05;
            app.AddLogoButton = uibutton(app.BasicoperationsTab, 'push');
            app.AddLogoButton.ButtonPushedFcn = createCallbackFcn(app, @AddLogoButtonPushed, true);
            app.AddLogoButton.Text = 'Add Logo';
            app.xEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.xEditField.Limits = [0.11 0.9];
            app.xEditField.Value = 0.805;
            app.yEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.yEditField.Limits = [0.11 0.9];
            app.yEditField.Value = 0.11;
            app.ConverttoHSVButton = uibutton(app.BasicoperationsTab, 'push');
            app.ConverttoHSVButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoHSVButtonPushed, true);
            app.ConverttoHSVButton.Text = 'Convert to HSV';
            app.gethistogramButton = uibutton(app.BasicoperationsTab, 'push');
            app.gethistogramButton.ButtonPushedFcn = createCallbackFcn(app, @gethistogramButtonPushed, true);
            app.gethistogramButton.Text = 'get histogram';
            app.histogramequlizationButton = uibutton(app.BasicoperationsTab, 'push');
            app.histogramequlizationButton.ButtonPushedFcn = createCallbackFcn(app, @histogramequlizationButtonPushed, true);
            app.histogramequlizationButton.Text = 'histogram equlization';
            app.BlurButton = uibutton(app.BasicoperationsTab, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.SobelOperatorButton = uibutton(app.BasicoperationsTab, 'push');
            app.SobelOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @SobelOperatorButtonPushed, true);
            app.SobelOperatorButton.Text = 'Sobel Operator';
            app.LaplacianOperatorButton = uibutton(app.BasicoperationsTab, 'push');
            app.LaplacianOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianOperatorButtonPushed, true);
            app.LaplacianOperatorButton.Text = 'Laplacian Operator';
            app.CannyOperatorButton = uibutton(app.BasicoperationsTab, 'push');
            app.CannyOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @CannyOperatorButtonPushed, true);
            app.CannyOperatorButton.Text = 'Canny Operator';
            app.HoughLinesButton = uibutton(app.BasicoperationsTab, 'push');
            app.HoughLinesButton.ButtonPushedFcn = createCallbackFcn(app, @HoughLinesButtonPushed, true);
            app.HoughLinesButton.Text = 'HoughLines';
            app.HoughCirclesButton = uibutton(app.BasicoperationsTab, 'push');
            app.HoughCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @HoughCirclesButtonPushed, true);
            app.HoughCirclesButton.Text = 'Hough Circles';
            app.RadiusmaxEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.RadiusmaxEditFieldLabel.Text = 'Radius max';
            app.RadiusmaxEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.RadiusmaxEditField.Limits = [0 500];
            app.RadiusmaxEditField.Value = 90;
            app.RadiusminEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.RadiusminEditFieldLabel.Text = 'Radius min';
            app.RadiusminEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.RadiusminEditField.Limits = [0 100];
            app.RadiusminEditField.Value = 15;
            app.SigmaEditFieldLabel = uilabel(app.BasicoperationsTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.BasicoperationsTab, 'numeric');
            app.SigmaEditField.Limits = [0 100];
            app.SigmaEditField.Value = 10;
            app.UIAxes = uiaxes(app.BasicoperationsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxesO = uiaxes(app.BasicoperationsTab);
            title(app.UIAxesO, 'Title')
            xlabel(app.UIAxesO, 'X')
            ylabel(app.UIAxesO, 'Y')
            app.MorphologicalOperationTab = uitab(app.TabGroup);
            app.MorphologicalOperationTab.Title = 'Morphological Operation';
            app.DilateButton = uibutton(app.MorphologicalOperationTab, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.ErodeButton = uibutton(app.MorphologicalOperationTab, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.OpenButton = uibutton(app.MorphologicalOperationTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.MorphologicalOperationTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UploadButton = uibutton(app.MorphologicalOperationTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.xEditField_2Label = uilabel(app.MorphologicalOperationTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_Sx = uieditfield(app.MorphologicalOperationTab, 'numeric');
            app.xEditField_Sx.Limits = [0 20];
            app.xEditField_Sx.Value = 3;
            app.yEditField_2Label = uilabel(app.MorphologicalOperationTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_Sy = uieditfield(app.MorphologicalOperationTab, 'numeric');
            app.yEditField_Sy.Limits = [0 20];
            app.yEditField_Sy.Value = 3;
            app.NonFlatBallCheckBox = uicheckbox(app.MorphologicalOperationTab);
            app.NonFlatBallCheckBox.ValueChangedFcn = createCallbackFcn(app, @NonFlatBallCheckBoxValueChanged, true);
            app.NonFlatBallCheckBox.Text = 'Non-Flat Ball';
            app.DiskCheckBox = uicheckbox(app.MorphologicalOperationTab);
            app.DiskCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiskCheckBoxValueChanged, true);
            app.DiskCheckBox.Text = 'Disk';
            app.ChooseStructuringElementLabel = uilabel(app.MorphologicalOperationTab);
            app.ChooseStructuringElementLabel.Text = ' Choose Structuring Element ';
            app.RadiusEditFieldLabel = uilabel(app.MorphologicalOperationTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.MorphologicalOperationTab, 'numeric');
            app.RadiusEditField.Limits = [0 20];
            app.RadiusEditField.Value = 3;
            app.LineCheckBox = uicheckbox(app.MorphologicalOperationTab);
            app.LineCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineCheckBoxValueChanged, true);
            app.LineCheckBox.Text = 'Line';
            app.DimEditFieldLabel = uilabel(app.MorphologicalOperationTab);
            app.DimEditFieldLabel.Text = 'Dim';
            app.DimEditField = uieditfield(app.MorphologicalOperationTab, 'numeric');
            app.DimEditField.Limits = [0 20];
            app.DimEditField.Value = 3;
            app.PosEditFieldLabel = uilabel(app.MorphologicalOperationTab);
            app.PosEditFieldLabel.Text = 'Pos';
            app.PosEditField = uieditfield(app.MorphologicalOperationTab, 'numeric');
            app.PosEditField.Limits = [0 400];
            app.UIAxes1 = uiaxes(app.MorphologicalOperationTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxesO1 = uiaxes(app.MorphologicalOperationTab);
            title(app.UIAxesO1, 'Title')
            xlabel(app.UIAxesO1, 'X')
            ylabel(app.UIAxesO1, 'Y')
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.UploadphotoButton_2 = uibutton(app.FiltersTab, 'push');
            app.UploadphotoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButton_2Pushed, true);
            app.UploadphotoButton_2.Text = 'Upload photo';
            app.LogFilterButton = uibutton(app.FiltersTab, 'push');
            app.LogFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LogFilterButtonPushed, true);
            app.LogFilterButton.Text = 'Log Filter';
            app.KernalSizeEditFieldLabel = uilabel(app.FiltersTab);
            app.KernalSizeEditFieldLabel.Text = 'Kernal Size';
            app.KernalSizeEditField1 = uieditfield(app.FiltersTab, 'numeric');
            app.KernalSizeEditField1.Limits = [2 Inf];
            app.KernalSizeEditField1.Value = 3;
            app.FiltersLabel = uilabel(app.FiltersTab);
            app.FiltersLabel.Text = 'Filters:';
            app.PrewittButton = uibutton(app.FiltersTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.AverageButton = uibutton(app.FiltersTab, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.EditField1 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField1.Value = 2;
            app.EditField2 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField2.Value = 2;
            app.EditField3 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField3.Value = 2;
            app.EditField4 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField4.Value = -15;
            app.EditField5 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField5.Value = 2;
            app.EditField6 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField6.Value = 2;
            app.EditField7 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField7.Value = 2;
            app.EditField8 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField8.Value = 2;
            app.EditField9 = uieditfield(app.FiltersTab, 'numeric');
            app.EditField9.Value = 2;
            app.CustomizedKernalLabel = uilabel(app.FiltersTab);
            app.CustomizedKernalLabel.Text = 'Customized Kernal:';
            app.SigmaGaussianEditFieldLabel = uilabel(app.FiltersTab);
            app.SigmaGaussianEditFieldLabel.Text = 'Sigma Gaussian';
            app.SigmaGaussianEditField = uieditfield(app.FiltersTab, 'numeric');
            app.SigmaGaussianEditField.Limits = [0 1.5];
            app.SigmaGaussianEditField.Value = 0.3;
            app.ApplyFilterButton = uibutton(app.FiltersTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = {'Apply '; 'Filter'};
            app.XLabel = uilabel(app.FiltersTab);
            app.XLabel.Text = 'X';
            app.KernalSizeEditField2 = uieditfield(app.FiltersTab, 'numeric');
            app.KernalSizeEditField2.Limits = [2 Inf];
            app.KernalSizeEditField2.Value = 3;
            app.DiskButton = uibutton(app.FiltersTab, 'push');
            app.DiskButton.ButtonPushedFcn = createCallbackFcn(app, @DiskButtonPushed, true);
            app.DiskButton.Text = 'Disk';
            app.DiskRadiusEditFieldLabel = uilabel(app.FiltersTab);
            app.DiskRadiusEditFieldLabel.Text = 'Disk Radius';
            app.DiskRadiusEditField = uieditfield(app.FiltersTab, 'numeric');
            app.DiskRadiusEditField.Limits = [0 Inf];
            app.DiskRadiusEditField.Value = 18;
            app.UIAxes2 = uiaxes(app.FiltersTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxesO2 = uiaxes(app.FiltersTab);
            title(app.UIAxesO2, 'Title')
            xlabel(app.UIAxesO2, 'X')
            ylabel(app.UIAxesO2, 'Y')
            app.ContoursTab = uitab(app.TabGroup);
            app.ContoursTab.Title = 'Contours';
            app.UploadphotoButton_3 = uibutton(app.ContoursTab, 'push');
            app.UploadphotoButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButton_3Pushed, true);
            app.UploadphotoButton_3.Text = 'Upload photo';
            app.FindConnectedButton = uibutton(app.ContoursTab, 'push');
            app.FindConnectedButton.ButtonPushedFcn = createCallbackFcn(app, @FindConnectedButtonPushed, true);
            app.FindConnectedButton.Text = 'Find Connected';
            app.BoundingBoxButton = uibutton(app.ContoursTab, 'push');
            app.BoundingBoxButton.ButtonPushedFcn = createCallbackFcn(app, @BoundingBoxButtonPushed, true);
            app.BoundingBoxButton.Text = 'Bounding Box';
            app.DrawRedContourButton = uibutton(app.ContoursTab, 'push');
            app.DrawRedContourButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRedContourButtonPushed, true);
            app.DrawRedContourButton.Text = 'Draw-Red-Contour';
            app.UIAxes3 = uiaxes(app.ContoursTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxesO3 = uiaxes(app.ContoursTab);
            title(app.UIAxesO3, 'Title')
            xlabel(app.UIAxesO3, 'X')
            ylabel(app.UIAxesO3, 'Y')
            app.videoTab = uitab(app.TabGroup);
            app.videoTab.Title = 'video';
            app.AddNoisePanel = uipanel(app.videoTab);
            app.AddNoisePanel.Title = 'Add Noise';
            app.addSPNoiseButton_2 = uibutton(app.AddNoisePanel, 'push');
            app.addSPNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @addSPNoiseButton_2Pushed, true);
            app.addSPNoiseButton_2.Text = 'add S&P Noise';
            app.GaussiannoiseButton = uibutton(app.AddNoisePanel, 'push');
            app.GaussiannoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussiannoiseButtonPushed, true);
            app.GaussiannoiseButton.Text = 'Gaussian noise';
            app.PoissonNoiseButton = uibutton(app.AddNoisePanel, 'push');
            app.PoissonNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonNoiseButtonPushed, true);
            app.PoissonNoiseButton.Text = 'Poisson Noise';
            app.NoiseDensityEditFieldLabel = uilabel(app.AddNoisePanel);
            app.NoiseDensityEditFieldLabel.Text = 'Noise Density';
            app.NoiseDensityEditField = uieditfield(app.AddNoisePanel, 'numeric');
            app.NoiseDensityEditField.Limits = [0 1];
            app.NoiseDensityEditField.Value = 0.5;
            app.UIAxesvideo = uiaxes(app.videoTab);
            title(app.UIAxesvideo, 'Title')
            xlabel(app.UIAxesvideo, 'X')
            ylabel(app.UIAxesvideo, 'Y')
            app.UIAxesvideoO = uiaxes(app.videoTab);
            title(app.UIAxesvideoO, 'Title')
            xlabel(app.UIAxesvideoO, 'X')
            ylabel(app.UIAxesvideoO, 'Y')
            app.UploadVideoButton = uibutton(app.videoTab, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'UploadVideo';
            app.FilteringPanel = uipanel(app.videoTab);
            app.FilteringPanel.Title = 'Filtering';
            app.converttoHSVButton = uibutton(app.FilteringPanel, 'push');
            app.converttoHSVButton.ButtonPushedFcn = createCallbackFcn(app, @converttoHSVButtonPushed, true);
            app.converttoHSVButton.Text = 'convert to HSV';
            app.BlurVideoButton = uibutton(app.FilteringPanel, 'push');
            app.BlurVideoButton.ButtonPushedFcn = createCallbackFcn(app, @BlurVideoButtonPushed, true);
            app.BlurVideoButton.Text = 'Blur Video';
            app.BlurValueEditFieldLabel = uilabel(app.FilteringPanel);
            app.BlurValueEditFieldLabel.Text = 'Blur Value';
            app.BlurValueEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.BlurValueEditField.Limits = [0 100];
            app.BlurValueEditField.Value = 8;
            app.Rgb2grayButton = uibutton(app.FilteringPanel, 'push');
            app.Rgb2grayButton.ButtonPushedFcn = createCallbackFcn(app, @Rgb2grayButtonPushed, true);
            app.Rgb2grayButton.Text = 'Rgb2gray';
            app.EdgeDetectionPanel = uipanel(app.videoTab);
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.SobelButton = uibutton(app.EdgeDetectionPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplacianOperatorButton_2 = uibutton(app.EdgeDetectionPanel, 'push');
            app.LaplacianOperatorButton_2.ButtonPushedFcn = createCallbackFcn(app, @LaplacianOperatorButton_2Pushed, true);
            app.LaplacianOperatorButton_2.Text = 'Laplacian Operator';
            app.CannyOperatorButton_2 = uibutton(app.EdgeDetectionPanel, 'push');
            app.CannyOperatorButton_2.ButtonPushedFcn = createCallbackFcn(app, @CannyOperatorButton_2Pushed, true);
            app.CannyOperatorButton_2.Text = 'Canny Operator';
            app.PrewittButton_2 = uibutton(app.EdgeDetectionPanel, 'push');
            app.PrewittButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrewittButton_2Pushed, true);
            app.PrewittButton_2.Text = 'Prewitt';
            app.RobertsButton = uibutton(app.EdgeDetectionPanel, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CVtoolbox

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end