classdef mathapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        matcherTab                matlab.ui.container.Tab
        CommandlineTextAreaLabel  matlab.ui.control.Label
        CommandlineTextArea       matlab.ui.control.TextArea
        Label                     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        HelpTab                   matlab.ui.container.Tab
        ListofCommands1hello2no3yes4matchLabel  matlab.ui.control.Label
        HowToUseApplicationLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: CommandlineTextArea
        function CommandlineTextAreaValueChanged(app, event)
            value = app.CommandlineTextArea.Value;
            
           
              %list of commands
            Command3='yes';            
            Command2='bye';
            Command1='hello';
            Command0='match me';
            Command01='match';
            Command10='no';
            Command5 = 'matchnext';
            
            
            Current_Folder = pwd;  %project directory
            
            trainingImagePath      = strcat(Current_Folder,'\trainingset');
            testingImagePath       = strcat(Current_Folder,'\testingset');


         for k = 1:length(value)    
            if(strcmp(value{k},Command1))
                app.Label.Text = 'hello, can I take your picture and add it to my dataset ?'; 
                break;
            elseif(strcmp(value{k},Command2))
                app.Label.Text = 'byebye';
                pause(2);
               close all force;
                break;


            elseif (strncmp(value{k},Command3,3))
                cam = webcam(1);
                preview(cam);
                [name remain] = strtok(value{k},'!');
                 for i =1:3
                       Im = snapshot(cam);
                      faceDetector = vision.CascadeObjectDetector;
                      bboxes = step(faceDetector, Im);
                      face = Im(bboxes(1,2):bboxes(1,2)+bboxes(1,4),bboxes(1,1):bboxes(1,1)+bboxes(1,3));
                        %imshow(face);
                       face2 = imresize( face, [64 64]);    
                     imwrite(face2,strcat(trainingImagePath,'\',remain,num2str(i),'.jpg'));
                     pause(1);
                 end
                 for i =4:5
                      Im = snapshot(cam);
                      faceDetector = vision.CascadeObjectDetector;
                      bboxes = step(faceDetector, Im);
                      face = Im(bboxes(1,2):bboxes(1,2)+bboxes(1,4),bboxes(1,1):bboxes(1,1)+bboxes(1,3));
            
                      face2 = imresize( face, [64 64]);    
                     imwrite(face2,strcat(testingImagePath,'\',remain,num2str(i),'.jpg'));
                      pause(1);
                 end
                     break;
            elseif (strcmp(value{k}, Command10))
                app.Label.Text = ' ';
                break;     
            elseif (strcmp(value{k},Command01))
                           app.Label.Text = ' ';
                          trainingImages = dir(cat(2,trainingImagePath,'\*.jpg'));
                          noOfTrainimages = length(trainingImages);
            
                           %Creating the training dataset
                          trainingdataset = zeros(noOfTrainimages,64*64);   %creating the dataset with the same size of number of images
            
                  for i = 1:noOfTrainimages
            	       %read image and convert to double and add to trainingdataset as rows
            	        imageReader = imread(char(strcat(trainingImagePath,'\',trainingImages(i).name(1:end-4), '.jpg'))); %reading each image along with its name
                        trainingdataset(i,:) = reshape(imageReader,1,64*64);%reshaping each image into a row in the data matrix
                  end
             
                    trainingdatasetaveraged = trainingdataset - mean(trainingdataset')';
            
            %then get the covariance of the dataSet
                    trainingdatasetaveragedCovar = trainingdatasetaveraged' * trainingdatasetaveraged / noOfTrainimages;
            
                         k = 100;
                         
                       [V,D] = eigs(trainingdatasetaveragedCovar,k,'lm');
                         projection = trainingdataset * V;
            
                     %Open test faces
                         imagetestingfiles = dir(cat(2,testingImagePath,'\*.jpg'));
                         noOftestingimages = length(imagetestingfiles);
                          
                          [FileName,PathName] = uigetfile('*.jpg','Select Image',testingImagePath);
                                      app.UIFigure.Visible ='off';  %fixing matlab bug by hiding and showing the application because of the previous bug
                                      app.UIFigure.Visible = 'on';  %showing the app again
                                        if ~ischar(FileName)
                                         return;
                                        end
                                      image=imread(strcat(PathName,FileName));
                                      imTesting = double(image);
                                      chosentestimage = reshape(imTesting,1,64*64);
                                     
                           projTestimage = chosentestimage * V;
                                imshow(image,'parent',app.UIAxes);
                            %axis(app.UIAxes,'on');  if you want to show the axes
                           
                           error_vec = zeros(1,noOfTrainimages);
                           for t = 1:noOfTrainimages
                                 error_vec(t) = immse(projTestimage,projection(t,:));
                           end
                                iter= 1;
                              [out,error_vect_orderd] = sort(error_vec);
                           
                              imshow(reshape(trainingdataset(error_vect_orderd(iter),:),64,64),[], 'parent' ,app.UIAxes_2);
                                app.Label_3.Text = trainingImages(error_vect_orderd(iter)).name;   
                                app.Label_2.Text =FileName;
                                
                                            x = inputdlg('Number of Next best Match',...
                                                                   'Match Number', [1 15]);
                                                                app.UIFigure.Visible ='off';
                                                                app.UIFigure.Visible = 'on';
                                                               
                                                             data = str2num(x{:}); 
                                   
                                                            
                              % app.Label_4.Text = strcat('Error = ',num2str(error_vec(error_vect_orderd(data))));
                     
                   while ~data==0       
                           imshow(reshape(trainingdataset(error_vect_orderd(data),:),64,64),[], 'parent' ,app.UIAxes_2);
                           app.Label_3.Text = trainingImages(error_vect_orderd(data)).name;
                                         x = inputdlg('Number of Next best Match :',...
                                                                   'Match Number', [1 15]);
                                                              data = str2num(x{:}); 
                                                              
                             
                   end               
                                break;  
                     
            end
         end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.matcherTab = uitab(app.TabGroup);
            app.matcherTab.Title = 'matcher';
            app.CommandlineTextAreaLabel = uilabel(app.matcherTab);
            app.CommandlineTextAreaLabel.Text = 'Command line';
            app.CommandlineTextArea = uitextarea(app.matcherTab);
            app.CommandlineTextArea.ValueChangedFcn = createCallbackFcn(app, @CommandlineTextAreaValueChanged, true);
            app.Label = uilabel(app.matcherTab);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.matcherTab);
            title(app.UIAxes, 'TestImage')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.matcherTab);
            title(app.UIAxes_2, 'ImageFound')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Label_2 = uilabel(app.matcherTab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.matcherTab);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.matcherTab);
            app.Label_4.Text = '';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.ListofCommands1hello2no3yes4matchLabel = uilabel(app.HelpTab);
            app.ListofCommands1hello2no3yes4matchLabel.Text = {'List of Commands:'; ''; '1) "hello"'; '2) "no"'; '3) "yes"'; '4) "match"'; '5) "bye" '; ''; 'note: press tab after '; 'each Command.'};
            app.HowToUseApplicationLabel = uilabel(app.HelpTab);
            app.HowToUseApplicationLabel.Text = {'How To Use Application:'; ''; '1) start by saying hello !! '; ''; '2) if you want to get scanned by the camera say yes'; ' followed by ! and your name, example: "yes!Karim" , '; 'otherwise say "no"'; ''; '3) if you want to start matching faces, type "match"'; ''; '4) Choose your test Photo, you will find your '; 'photo in the test folder if you said yes to scanning'; ''; 'Note: while Scanning Change your head position by moving'; ' your head little right and left. to be fair with other acquisitions.'; ''; '5) best first matched photo will appear'; ''; '6) input dialog box will ask you to give the number of  the'; ' next best match, if you want to exit that dialog write number  "0"'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = mathapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end